classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConnectButton                  matlab.ui.control.Button
        InitializationLabel            matlab.ui.control.Label
        PortconnectDropDown            matlab.ui.control.DropDown
        PortconnectDropDownLabel       matlab.ui.control.Label
        SavedataButton                 matlab.ui.control.Button
        IDSControlStationLabel         matlab.ui.control.Label
        StepstoinitializeListBox       matlab.ui.control.ListBox
        StepstoinitializeListBoxLabel  matlab.ui.control.Label
        CurrentdronesondeckListBox     matlab.ui.control.ListBox
        CurrentdronesondeckListBoxLabel  matlab.ui.control.Label
        ControlPanelLabel              matlab.ui.control.Label
        ConfigurationtoolsLabel        matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        ShowfigureButton               matlab.ui.control.Button
        LengthEditFieldLabel           matlab.ui.control.Label
        ConfirmButton                  matlab.ui.control.Button
        MonitorTextArea_2              matlab.ui.control.TextArea
        TestButton                     matlab.ui.control.Button
        MonitorTextArea_2Label         matlab.ui.control.Label
        AbortButton                    matlab.ui.control.Button
        TakeoffButton                  matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Readtimer;
        FireAIDSSController;  % Integrated FireAIDSS controller
        s;  % Serial connection
        Cdata;  % Current sensor data (40x40x10x4)
        Pdata;  % Current predictions (40x40x10x4)
        L; W; R;  % Configuration parameters
        Hold_flag = 0;
        flight_mode = "simple";  % "simple" or "adaptive" - controlled via R parameter
     end
    
    methods (Access = private)
        function updateMonitor(app, message)
            timestamp = datestr(now, 'HH:MM:SS');
            newText = sprintf('[%s] %s', timestamp, message);
            app.MonitorTextArea_2.Value = newText;
        end
        
        function npArray = matlabToNumpy(~, matlabMatrix)
            % Convert MATLAB matrix to Python NumPy array
            pyArray = py.numpy.array(matlabMatrix);
            npArray = pyArray;
        end
        
        function matlabMatrix = numpyToMatlab(~, npArray)
            matlabMatrix = double(npArray);
        end
        function callback_read(app)
            if app.s.NumBytesAvailable > 0
                try
                    data = readline(app.s);
                    app.updateMonitor(['Received: ' char(data)]);
                    
                    % Parse sensor data: "id temp xspd yspd zspd"
                    data_split = split(data, " ");
                    if length(data_split) >= 5
                        data_numeric = str2double(data_split);
                        
                        if ~any(isnan(data_numeric))
                            drone_id = data_numeric(1);
                            temperature = data_numeric(2);
                            wind_x = data_numeric(3);
                            wind_y = data_numeric(4);
                            wind_z = data_numeric(5);
                            
                            % Update controller with sensor data
                            if ~isempty(app.FireAIDSSController)
                                % Get drone position (dummy for now - should come from position system)
                                position = py.numpy.array([0.0, 0.0, 0.5]);
                                
                                app.FireAIDSSController.update_sensor_reading(...
                                    py.int(drone_id), position, py.float(temperature), ...
                                    py.float(wind_x), py.float(wind_y), py.float(wind_z));
                                
                                % Get updated predictions
                                predictions = app.FireAIDSSController.get_predictions_matlab();
                                app.Pdata = app.numpyToMatlab(predictions);
                                
                                % Update visualization
                                app.updateVisualization();
                            end
                        end
                    end
                catch ME
                    app.updateMonitor(['Data parsing error: ' ME.message]);
                end
            end
        end

         function updateVisualization(app)
            try
                % Update sensor data visualization (UIAxes1)
                cla(app.UIAxes1);
                
                % Show temperature field at fixed Z level (grid layer 5 = 0.5m height)
                temp_data = app.Pdata(:,:,5,1);  % Temperature at 0.5m height
                if any(temp_data(:) ~= 0)
                    [X, Y] = meshgrid(1:40, 1:40);
                    surf(app.UIAxes1, X, Y, temp_data);
                    title(app.UIAxes1, 'Grid Sensor Data - Temperature (0.5m)');
                    xlabel(app.UIAxes1, 'X Grid Points');
                    ylabel(app.UIAxes1, 'Y Grid Points');
                    zlabel(app.UIAxes1, 'Temperature (°C)');
                    colorbar(app.UIAxes1);
                end
                
                % Update prediction visualization (UIAxes2)
                cla(app.UIAxes2);
                pred_temp = app.Pdata(:,:,5,1);  
                if any(pred_temp(:) ~= 0)
                    [X, Y] = meshgrid(1:40, 1:40);
                    surf(app.UIAxes2, X, Y, pred_temp);
                    title(app.UIAxes2, 'AI Predictions - Temperature (0.5m)');
                    xlabel(app.UIAxes2, 'X Grid Points');
                    ylabel(app.UIAxes2, 'Y Grid Points');
                    zlabel(app.UIAxes2, 'Temperature (°C)');
                    colorbar(app.UIAxes2);
                    
                    % Add wind vectors at fixed Z level
                    hold(app.UIAxes2, 'on');
                    wind_x = app.Pdata(:,:,5,2);
                    wind_y = app.Pdata(:,:,5,3);
                    quiver(app.UIAxes2, X(1:4:end,1:4:end), Y(1:4:end,1:4:end), ...
                           wind_x(1:4:end,1:4:end), wind_y(1:4:end,1:4:end), 'k');
                    hold(app.UIAxes2, 'off');
                end
            catch ME
                app.updateMonitor(['Visualization error: ' ME.message]);
            end
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setenv('KMP_DUPLICATE_LIB_OK', 'TRUE');
            if count(py.sys.path, '') == 0
            insert(py.sys.path, int32(0), '');  % Add the current directory
            end
            % app.s = serialport("COM8",115200);
            % Initialize FireAIDSS Controller
            try
                app.updateMonitor('Initializing FireAIDSS Controller...');
                fireaidss_module = py.importlib.import_module('FireAIDSS_Controller');
                app.FireAIDSSController = fireaidss_module.create_controller();
                app.updateMonitor('FireAIDSS Controller initialized successfully');
            catch ME
                app.updateMonitor(['Error initializing controller: ' ME.message]);
                app.FireAIDSSController = [];
            end
            % Initialize Python environment
            setenv('KMP_DUPLICATE_LIB_OK', 'TRUE');
            if count(py.sys.path, '') == 0
                insert(py.sys.path, int32(0), '');
            end
            
            % Initialize FireAIDSS Controller
            try
                app.updateMonitor('Initializing FireAIDSS Controller...');
                fireaidss_module = py.importlib.import_module('FireAIDSS_Controller');
                app.FireAIDSSController = fireaidss_module.create_controller();
                app.updateMonitor('FireAIDSS Controller initialized successfully');
            catch ME
                app.updateMonitor(['Error initializing controller: ' ME.message]);
                app.FireAIDSSController = [];
            end
            
            % Initialize data arrays (40x40x10x4 for grid-based storage)
            app.Cdata = zeros(40, 40, 10, 4);
            app.Pdata = zeros(40, 40, 10, 4);
            
            % Setup timer for data reading
            app.Readtimer = timer("TimerFcn", @(obj,event) app.callback_read(), ...
                                 'Period', 0.1, 'ExecutionMode', 'fixedSpacing');
            
            app.updateMonitor('App started - Ready for operation');
            
            % Update initialization steps
            app.StepstoinitializeListBox.Items = {
                'Power on drones', 
                'Select serial port', 
                'Press Connect', 
                'Press Test', 
                'Set flight mode (R=0: simple, R=1: adaptive)',
                'Press Takeoff to start mission'
            };
            
            % Update drone status
            app.CurrentdronesondeckListBox.Items = {'Drone 1: Standby', 'Drone 2: Standby'};
        end

        % Button pushed function: TakeoffButton
        function TakeoffButtonPushed(app, event)
            if isempty(app.FireAIDSSController)
                app.updateMonitor('Error: Controller not initialized');
                return;
            end
            
            app.updateMonitor('Starting FireAIDSS mission...');
            app.Hold_flag = 0;
            
            % Set flight mode based on R parameter
            if app.R == 0
                app.flight_mode = "simple";
                app.updateMonitor('Flight mode: Simple zigzag pattern');
            else
                app.flight_mode = "adaptive";
                app.updateMonitor('Flight mode: Adaptive gradient-based');
            end
            
            app.FireAIDSSController.set_flight_mode(app.flight_mode);
            
            % Initial takeoff commands
            app.s.writeline("1,1500,1500,1500,1500,2/");
            pause(0.1);
            app.s.writeline("2,1500,1500,1500,1500,2/");
            pause(0.1);
            
            app.updateMonitor('Drones taking off...');
            
            % Main mission loop
            mission_steps = 300; 
            for i = 1:mission_steps
                if app.Hold_flag == 1
                    break;
                end
                
                try
                    % Get commands from controller
                    commands = app.FireAIDSSController.get_commands();
                    
                    % Send commands to drones
                    if isa(commands, 'py.dict')
                        command_keys = py.list(commands.keys());
                        for j = 1:length(command_keys)
                            drone_id = command_keys{j};
                            command = char(commands{drone_id});
                            app.s.writeline(command);
                            pause(0.05);
                        end
                    end
                    
                    % Update status
                    if mod(i, 50) == 0
                        app.updateMonitor(sprintf('Mission progress: %d/%d', i, mission_steps));
                        
                
                        n_measurements = sum(app.Pdata(:) ~= 0, 'all') / 4;
                        app.CurrentdronesondeckListBox.Items = {
                            sprintf('Drone 1: Active (%d measurements)', floor(n_measurements/2)),
                            sprintf('Drone 2: Active (%d measurements)', ceil(n_measurements/2))
                        };
                    end
                    
                    pause(0.2); 
                    
                catch ME
                    app.updateMonitor(['Mission error: ' ME.message]);
                    break;
                end
            end
            
            app.updateMonitor('Mission completed');
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
             app.Hold_flag = 1;
            app.updateMonitor('ABORT - Emergency landing initiated');
            
            % Send emergency landing commands
            for i = 1:10
                app.s.writeline("1,1500,1500,1000,1500,0/");
                pause(0.1);
                app.s.writeline("2,1500,1500,1000,1500,0/");
                pause(0.1);
            end
            
            pause(1);
            
            % Final landing commands
            app.s.writeline("1,1500,1500,1500,1500,1/");
            pause(0.1);
            app.s.writeline("2,1500,1500,1500,1500,1/");
            pause(0.1);
            
            % Stop timer and close connections
            if isvalid(app.Readtimer)
                stop(app.Readtimer);
                delete(app.Readtimer);
            end
            
            if ~isempty(app.s) && isvalid(app.s)
                fclose(app.s);
                delete(app.s);
            end
            
            app.updateMonitor('System shutdown complete');
            app.CurrentdronesondeckListBox.Items = {'Drone 1: Landed', 'Drone 2: Landed'};
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            value = app.LengthEditField.Value;
            app.L = value;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.W=value;
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            value = app.RadiusEditField.Value;
            app.R = value;
            
            if value == 0
                app.updateMonitor('Flight mode set to: Simple zigzag');
            else
                app.updateMonitor('Flight mode set to: Adaptive gradient');
            end
        end

        % Button pushed function: ShowfigureButton
        function ShowfigureButtonPushed(app, event)
            app.updateVisualization();
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.updateMonitor("Configuration confirmed");
            app.updateMonitor(sprintf("L=%g, W=%g, R=%g (0=simple, 1=adaptive)", app.L, app.W, app.R));
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            for i=1:1:10
                app.s.readline();
            end
             app.updateMonitor('Testing communication...');
            
            % Clear serial buffer
            if ~isempty(app.s) && isvalid(app.s)
                for i = 1:10
                    if app.s.NumBytesAvailable > 0
                        readline(app.s);
                    end
                end
            end
            
            % Start reading timer
            if isvalid(app.Readtimer)
                start(app.Readtimer);
                app.updateMonitor('Data reading started');
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            
            % Save grid-based sensor data
            sensor_filename = ['GridSensorData_' timestamp '.csv'];
            writematrix(reshape(app.Cdata, [], 4), sensor_filename);
            
            % Save prediction data
            pred_filename = ['GridPredictionData_' timestamp '.csv'];
            writematrix(reshape(app.Pdata, [], 4), pred_filename);
            
            app.updateMonitor(['Grid data saved: ' sensor_filename ' and ' pred_filename]);
        end

        % Callback function
        function PortconnectDropDownOpening(app, event)
             app.PortDropDown.Items=serialportlist('available');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           try
                Port = app.PortconnectDropDown.Value;
                app.s = serialport(Port, 115200);
                app.updateMonitor(['Connected to port: ' Port]);
                
                % Update initialization steps
                app.StepstoinitializeListBox.Items = {
                    'Port connected ✓', 
                    'Press Test to start data reading', 
                    'Verify drone communication', 
                    'Set flight mode (R parameter)',
                    'Press Takeoff to start mission'
                };
                
            catch ME
                app.updateMonitor(['Connection failed: ' ME.message]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Sensors')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Prediction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TakeoffButton = uibutton(app.UIFigure, 'push');
            app.TakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeoffButtonPushed, true);
            app.TakeoffButton.Text = 'Takeoff';
            app.AbortButton = uibutton(app.UIFigure, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.MonitorTextArea_2Label = uilabel(app.UIFigure);
            app.MonitorTextArea_2Label.Text = 'MonitorTextArea_2';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.MonitorTextArea_2 = uitextarea(app.UIFigure);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.ShowfigureButton = uibutton(app.UIFigure, 'push');
            app.ShowfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ShowfigureButtonPushed, true);
            app.ShowfigureButton.Text = 'Showfigure';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.ConfigurationtoolsLabel = uilabel(app.UIFigure);
            app.ConfigurationtoolsLabel.Text = 'Configuration tools';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.CurrentdronesondeckListBoxLabel = uilabel(app.UIFigure);
            app.CurrentdronesondeckListBoxLabel.Text = 'Current drones on deck:';
            app.CurrentdronesondeckListBox = uilistbox(app.UIFigure);
            app.CurrentdronesondeckListBox.Items = {'Alpha', 'Beta', 'Charlie'};
            app.CurrentdronesondeckListBox.Value = 'Alpha';
            app.StepstoinitializeListBoxLabel = uilabel(app.UIFigure);
            app.StepstoinitializeListBoxLabel.Text = 'Steps to initialize:';
            app.StepstoinitializeListBox = uilistbox(app.UIFigure);
            app.StepstoinitializeListBox.Items = {'Drones activate', 'Select Port', 'Press Test', 'Drones lock', 'Press Takeoff'};
            app.StepstoinitializeListBox.Value = 'Drones activate';
            app.IDSControlStationLabel = uilabel(app.UIFigure);
            app.IDSControlStationLabel.Text = 'IDS Control Station';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Savedata';
            app.PortconnectDropDownLabel = uilabel(app.UIFigure);
            app.PortconnectDropDownLabel.Text = 'Port connect';
            app.PortconnectDropDown = uidropdown(app.UIFigure);
            app.PortconnectDropDown.Items = {'COM8'};
            app.PortconnectDropDown.Value = 'COM8';
            app.InitializationLabel = uilabel(app.UIFigure);
            app.InitializationLabel.Text = 'Initialization';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end